classdef MApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EBSDDataAnalysisPanel      matlab.ui.container.Panel
        Label                      matlab.ui.control.Label
        CurrentData                matlab.ui.control.EditField
        CurrentDataEditFieldLabel  matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        DataTable                  matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        ImportEBSDDataButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        ImportedData % EBSD Data imported by import_data
        FileName % Name of the File, that got imported 

        PhaseID 
        Phases  % List, containing all phases 
        Points % List, containing the number of data-points each phase 
        Symmetry % Symmetry of all phases 
        Elements % ???
        Angles % List, containing all angles a,b,c of each phase 
        Color % List, containing RGB values of each phase 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportEBSDDataButton
        function ImportEBSDDataButtonPushed(app, event)
            app.DataTable.Data = [];
            removeStyle(app.DataTable);

            % create a style
            s = uistyle('HorizontalAlignment','left');           
            addStyle(app.DataTable,s)

            % Import EBSD Data using the import_data function 
            [data, fileName] = import_data();

            % case for termination of import window
            if isempty(data)
                return
            end

            app.ImportedData = data; 
            app.FileName = fileName;
            % Displaying the Name of the imported File
            app.CurrentData.Value = app.FileName;

            % fill UIAxes with the plotted data, only gets update once here
            % after pressing the 'Import EBSD' button
            % !!! Figure created by plot() cannot be closed
            cla(app.UIAxes);
            axes(app.UIAxes);
            plot(app.ImportedData,'parent',app.UIAxes);


            % new way of filling out DataTable

            l = length(app.ImportedData.CSList);
            
            for i = 1:1:l

                app.PhaseID{i} = app.ImportedData.phaseMap(i);
                cs = app.ImportedData.CSList{i};

                if strcmp(cs,'notIndexed')
                    app.Phases{i} = 'NotIndexed';
                    app.Points{i} = nnz(app.ImportedData.phaseId == app.PhaseID{i});
                    app.Symmetry{i} = 'None';
                    app.Angles.a{i} = 0;
                    app.Angles.b{i} = 0;
                    app.Angles.c{i} = 0;
                else
                    app.Phases{i} = app.ImportedData.CSList{i}.mineral;
                    app.Points{i} = nnz(app.ImportedData.phaseId == app.PhaseID{i});
                    app.Symmetry{i} = app.ImportedData.CSList{i}.pointGroup;
                    app.Angles.a{i} = app.ImportedData.CSList{i}.aAxis.length;
                    app.Angles.b{i} = app.ImportedData.CSList{i}.bAxis.length;
                    app.Angles.c{i} = app.ImportedData.CSList{i}.cAxis.length;

                    app.Color{i} = app.ImportedData.CSList{i}.color;

                    s.BackgroundColor = [app.Color{i}];
                    addStyle(app.DataTable,s,"cell",[i,8;i,9;i,10]);


                end
                
                % create new entry for 
                newEntry = {app.PhaseID{i},app.Phases{i},app.Points{i},app.Symmetry{i}, app.Angles.a{i},app.Angles.b{i},app.Angles.c{i},''};
                app.DataTable.Data = [app.DataTable.Data;newEntry];

            end
        end

        % Cell edit callback: DataTable
        function DataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            changed_mineral_name = app.DataTable.Data{indices(1), 1};
                
            % Check if angles (alpha,beta,gamma) have been changed
            if (5 <= indices(2)) && (indices(2) <= 7)
                
                % Values cant be assigned yet

                % Alpha-Value has been changed
                if indices(2) == 5
                    app.ImportedData(changed_mineral_name).CS.alpha = newData;
                % Beta-Value has been changed
                elseif indices(2) == 6
                    app.ImportedData(changed_mineral_name).CS.beta = newData;
                % Gamma-Value has been changed
                elseif indices(2) == 7
                    app.ImportedData(changed_mineral_name).CS.gamma = newData;
                end

            % Check if type of symmetry has been changed
            elseif indices(2) == 3


            end

            
            % update plot in UIAxes (still opens plot windows of plot()
            % function) 
            cla(app.UIAxes);
            axes(app.UIAxes);
            plot(app.ImportedData,'parent',app.UIAxes);
            
            
        end

        % Callback function
        function DataTableSelectionChanged(app, event)

        end

        % Cell selection callback: DataTable
        function DataTableCellSelection(app, event)
            indices = event.Indices;
            
            % check if indices has a invalid value or if 'NotIndexed' color
            % cell has been selected, if so, return to prohibit change 
            if isempty(indices)
                return
            elseif strcmp(app.Phases{indices(1)},'NotIndexed')
                return
            end

            rows = indices(1);
            cols = indices(2);

            % check for selection of cell 8 (Color cell)
            if cols == 8

                NewColor = uisetcolor(app.Color{rows});

                % case for termination of uisetcolor window
                if NewColor == 0 
                    return
                end

                app.ImportedData.CSList{rows}.color = NewColor;
                app.Color{rows} = NewColor;
                % change color of selected cell
                s = uistyle;
                s.BackgroundColor = [app.ImportedData.CSList{rows}.color];
                addStyle(app.DataTable,s,"cell",[rows,cols]);
            end

            % update plot in UIAxes (still opens plot windows of plot()
            % function) 
            cla(app.UIAxes);
            axes(app.UIAxes);
            plot(app.ImportedData,'parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EBSDDataAnalysisPanel = uipanel(app.UIFigure);
            app.EBSDDataAnalysisPanel.Title = 'EBSD Data Analysis';
            app.ImportEBSDDataButton = uibutton(app.EBSDDataAnalysisPanel, 'push');
            app.ImportEBSDDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEBSDDataButtonPushed, true);
            app.ImportEBSDDataButton.Text = 'Import EBSD-Data';
            app.Panel = uipanel(app.EBSDDataAnalysisPanel);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataTable = uitable(app.Panel);
            app.DataTable.ColumnName = {'PhaseID'; 'Phase'; '# Orientations'; 'Symmetry'; 'a'; 'b'; 'c'; 'Color'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnEditable = [false false false true false true true true true];
            app.DataTable.CellEditCallback = createCallbackFcn(app, @DataTableCellEdit, true);
            app.DataTable.CellSelectionCallback = createCallbackFcn(app, @DataTableCellSelection, true);
            app.CurrentDataEditFieldLabel = uilabel(app.EBSDDataAnalysisPanel);
            app.CurrentDataEditFieldLabel.Text = 'Current Data';
            app.CurrentData = uieditfield(app.EBSDDataAnalysisPanel, 'text');
            app.CurrentData.Editable = 'off';
            app.Label = uilabel(app.EBSDDataAnalysisPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end